classdef Componentes_Simetricas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        GraficaTab                   matlab.ui.container.Tab
        UIAxes3                      matlab.ui.control.UIAxes
        VoltajesTab                  matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        ComSimetricasTab             matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        TabGroup2                    matlab.ui.container.TabGroup
        MagnitudTab                  matlab.ui.container.Tab
        MagnituddevoltajePanel       matlab.ui.container.Panel
        VabSliderLabel               matlab.ui.control.Label
        VabSlider                    matlab.ui.control.Slider
        VbcSliderLabel               matlab.ui.control.Label
        VbcSlider                    matlab.ui.control.Slider
        VcaSliderLabel               matlab.ui.control.Label
        VcaSlider                    matlab.ui.control.Slider
        VabEditFieldLabel            matlab.ui.control.Label
        VabEditField                 matlab.ui.control.NumericEditField
        VbcEditFieldLabel            matlab.ui.control.Label
        VbcEditField                 matlab.ui.control.NumericEditField
        VcaEditFieldLabel            matlab.ui.control.Label
        VcaEditField                 matlab.ui.control.NumericEditField
        AccionesRpidasButtonGroup    matlab.ui.container.ButtonGroup
        EquilibradoButton            matlab.ui.control.RadioButton
        DesequilibradoButton         matlab.ui.control.RadioButton
        AnguloTab                    matlab.ui.container.Tab
        AngulogradosPanel            matlab.ui.container.Panel
        AabSliderLabel               matlab.ui.control.Label
        AabSlider                    matlab.ui.control.Slider
        AbcSliderLabel               matlab.ui.control.Label
        AbcSlider                    matlab.ui.control.Slider
        AcaSliderLabel               matlab.ui.control.Label
        AcaSlider                    matlab.ui.control.Slider
        AabEditFieldLabel            matlab.ui.control.Label
        AabEditField                 matlab.ui.control.NumericEditField
        AbcEditFieldLabel            matlab.ui.control.Label
        AbcEditField                 matlab.ui.control.NumericEditField
        AcaEditFieldLabel            matlab.ui.control.Label
        AcaEditField                 matlab.ui.control.NumericEditField
        AccionesRpidasButtonGroup_2  matlab.ui.container.ButtonGroup
        EquilibradoButton_2          matlab.ui.control.RadioButton
        DesequilibradoButton_2       matlab.ui.control.RadioButton
        COMPONENTESSIMTRICASLabel    matlab.ui.control.Label
        VoltajesyComponentesSimtricasPanel  matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        ProgramarealizadoporErikPaneluisael30deAbrildel2023Label  matlab.ui.control.Label
    end

   
    properties (Access = private)
        A % Array de A para las componentes simetricas
        a % 1 ang 120
    end
    
    methods (Access = private)
        
        function grafica(~, axes, fasorPolar)
            plot(axes, [0 fasorPolar{1}(1)], [0 fasorPolar{2}(1)], 'b', 'LineWidth' ,2);
            hold(axes, 'on');
            plot(axes, [0 fasorPolar{1}(2)], [0 fasorPolar{2}(2)], 'r', 'LineWidth' ,2);
            plot(axes, [0 fasorPolar{1}(3)], [0 fasorPolar{2}(3)], 'y', 'LineWidth' ,2);
            axis(axes, "square");
            hold(axes, 'off');

        end
        
        function results = setFasorPolar(~, V, Ang) %Aqui ingresan en forma de matriz
            v = zeros(1, length(V));
            re = zeros(1, length(V));
            im = zeros(1, length(V));
            
            for j=1:1:length(V)
                v(j) = V(j)*exp(1i*Ang(j)*pi/180);
                re(j) = real(v(j));
                im(j) = imag(v(j));
            end

            results = {re; im; v};
        end
        
        function setText(app)
            app.Label.Text = "Vab =" + app.VabEditField.Value + "∠" + app.AabEditField.Value + "°";
            app.Label_2.Text = "Vbc =" + app.VbcEditField.Value + "∠" + app.AbcEditField.Value + "°";
            app.Label_3.Text = "Vca =" + app.VcaEditField.Value + "∠" + app.AcaEditField.Value + "°";
        end
        function setText2(app, M, A) %M=magnitud, A=angulo
            data = app.setFasorPolar(M, A);
            app.Label_4.Text = "Va(0) =" + M(1) + "∠" + A(1) + "°";
            app.Label_5.Text = "Va(1) =" + M(2) + "∠" + A(2) + "°";
            app.Label_6.Text = "Va(2) =" + M(3) + "∠" + A(3) + "°";
        end
        
        function results = setCompSim(app, V)
            v = app.A\V;
            results = v;
        end
        
        function results = totalCompSim(app, v) %recibe las componentes simetricas de una fase
            va = [v(1) v(2) v(3)];
            vb = [v(1) (v(2)*app.a*app.a) (v(3)*app.a)];
            vc = [v(1) (v(2)*app.a) (v(3)*app.a*app.a)];
            ar = {va;vb;vc};
            results = ar;
        end
        
        function graficaComSim(app, axes, comSim, fasorPolar) %recibe dos array        
            
            fa = [comSim{1}(1) comSim{1}(2) comSim{1}(3)];
            fb = [comSim{2}(1) comSim{2}(2) comSim{2}(3)];
            fc = [comSim{3}(1) comSim{3}(2) comSim{3}(3)];
            
            plot(axes, [0 fasorPolar{1}(1)], [0 fasorPolar{2}(1)], 'b', 'LineWidth' ,2);
            hold(axes, 'on');
            ra1 = real(fa(1));
            ra2 = real(fa(2));
            ra3 = real(fa(3));
            ia1 = -imag(fa(1));
            ia2 = -imag(fa(2));
            ia3 = -imag(fa(3)); 
            plot(axes, [0 ra2], [0 ia2]);
            plot(axes, [ra2 (ra2+ra3)], [ia2 (ia2+ia3)]);
            plot(axes, [(ra2+ra3) (ra1+ra2+ra3)], [(ia2+ia3) (ia1+ia2+ia3)]);
            
            plot(axes, [0 fasorPolar{1}(2)], [0 fasorPolar{2}(2)], 'r', 'LineWidth' ,2);
            ra1 = real(fb(1));
            ra2 = real(fb(2));
            ra3 = real(fb(3));
            ia1 = -imag(fb(1));
            ia2 = -imag(fb(2));
            ia3 = -imag(fb(3));
            plot(axes, [0 ra2], [0 ia2]);
            plot(axes, [ra2 (ra2+ra3)], [ia2 (ia2+ia3)]);
            plot(axes, [(ra2+ra3) (ra1+ra2+ra3)], [(ia2+ia3) (ia1+ia2+ia3)]);
             
            plot(axes, [0 fasorPolar{1}(3)], [0 fasorPolar{2}(3)], 'y', 'LineWidth' ,2);
            ra1 = real(fc(1));
            ra2 = real(fc(2));
            ra3 = real(fc(3));
            ia1 = -imag(fc(1));
            ia2 = -imag(fc(2));
            ia3 = -imag(fc(3));
            plot(axes, [0 ra2], [0 ia2]);
            plot(axes, [ra2 (ra2+ra3)], [ia2 (ia2+ia3)]);
            plot(axes, [(ra2+ra3) (ra1+ra2+ra3)], [(ia2+ia3) (ia1+ia2+ia3)]);
          
            hold(axes, 'off');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            
            %Matriz A para las componentes simetricas
            app.a = exp(1i*2*pi/3);
            app.A = [ 1 1 1; 1 app.a*app.a app.a; 1 app.a app.a*app.a];
            
            %Para los sliders de la magnitud de voltaje
            app.VabSlider.Value = 240;
            app.VbcSlider.Value = 240;
            app.VcaSlider.Value = 240;
            
            %Para los EditField de las magnitudes de voltaje
            app.VabEditField.Value = app.VabSlider.Value;
            app.VbcEditField.Value = app.VbcSlider.Value;
            app.VcaEditField.Value = app.VcaSlider.Value;
            
            %Para los sliders del angulo de voltaje
            app.AabSlider.Value = 0;
            app.AbcSlider.Value = 120;
            app.AcaSlider.Value = 240;
            
            %Para los EditField del angulo de voltaje
            app.AabEditField.Value = app.AabSlider.Value;
            app.AbcEditField.Value = app.AbcSlider.Value;
            app.AcaEditField.Value = app.AcaSlider.Value;
            
            app.setText();
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data); 
        
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi; %poner esto
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2);
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);
        end

        % Value changing function: VabSlider
        function VabSliderValueChanging(app, event)
            changingValue = event.Value;
            app.VabEditField.Value = changingValue;
            
            app.setText();
             
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2);
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Value changing function: VbcSlider
        function VbcSliderValueChanging(app, event)
            changingValue = event.Value;
            app.VbcEditField.Value = changingValue;
            
            app.setText();
             
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2);    
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Value changing function: VcaSlider
        function VcaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.VcaEditField.Value = changingValue;
            
            app.setText();
            
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2);   
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Value changing function: AabSlider
        function AabSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AabEditField.Value = changingValue;
            
            app.setText();
            
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2);     
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Value changing function: AbcSlider
        function AbcSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AbcEditField.Value = changingValue;
            
            app.setText();
            
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2);     
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Value changing function: AcaSlider
        function AcaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AcaEditField.Value = changingValue;
            
            app.setText();
            
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2);    
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Value changed function: AabEditField
        function AabEditFieldValueChanged(app, event)
             
            app.setText();
            
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2);   
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Value changed function: AbcEditField
        function AbcEditFieldValueChanged(app, event)

            app.setText();
            
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2);  
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Value changed function: AcaEditField
        function AcaEditFieldValueChanged(app, event)

            app.setText();
            
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2);   
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Value changed function: VabEditField
        function VabEditFieldValueChanged(app, event)

            app.setText();
            
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2); 
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Value changed function: VbcEditField
        function VbcEditFieldValueChanged(app, event)

            app.setText();
            
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2); 
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Value changed function: VcaEditField
        function VcaEditFieldValueChanged(app, event)

            app.setText();
            
            %para gráficar
            V = [app.VabEditField.Value app.VbcEditField.Value app.VcaEditField.Value];
            Ang = [app.AabEditField.Value app.AbcEditField.Value app.AcaEditField.Value];
            data = app.setFasorPolar(V, Ang);
            app.grafica(app.UIAxes, data);
            
            compSim = app.setCompSim(data{3}');
            Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
            Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
            data2 = app.setFasorPolar(Magnitud, Angulo);
            app.grafica(app.UIAxes2, data2); 
            app.setText2(Magnitud, Angulo);
            
            totalcS = app.totalCompSim(compSim);
            app.graficaComSim(app.UIAxes3 ,totalcS, data);            
        end

        % Selection changed function: AccionesRpidasButtonGroup_2
        function AccionesRpidasButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.AccionesRpidasButtonGroup_2.SelectedObject;
            val = selectedButton.Text;
            
            if strcmp(val, 'Equilibrado')
                %para gráficar
                app.AabEditField.Value = 0;
                app.VabEditField.Value = 120;
                V = [app.VabEditField.Value app.VabEditField.Value app.VabEditField.Value];
                Ang = [app.AabEditField.Value (app.AabEditField.Value + 120) (app.AabEditField.Value + 240)];
                data = app.setFasorPolar(V, Ang);
                app.grafica(app.UIAxes, data);
                %magnitudes
                app.VbcEditField.Value = app.VabEditField.Value;
                app.VcaEditField.Value = app.VabEditField.Value;
                app.VabSlider.Value = app.VabEditField.Value;
                app.VbcSlider.Value = app.VabEditField.Value;
                app.VcaSlider.Value = app.VabEditField.Value;
                %angulos
                app.AbcEditField.Value = app.AabEditField.Value + 120;
                app.AcaEditField.Value = app.AabEditField.Value + 240;
                app.AabSlider.Value = app.AabEditField.Value;
                app.AbcSlider.Value = app.AabEditField.Value + 120;
                app.AcaSlider.Value = app.AabEditField.Value + 240;
                
                compSim = app.setCompSim(data{3}');
                Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
                Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
                data2 = app.setFasorPolar(Magnitud, Angulo);
                app.grafica(app.UIAxes2, data2);   
                app.setText2(Magnitud, Angulo);
                
                totalcS = app.totalCompSim(compSim);
                app.graficaComSim(app.UIAxes3 ,totalcS, data);                
                    
                app.setText();
                
            elseif strcmp(val, 'Desequilibrado')
                %para gráficar
                app.AabEditField.Value = 0;
                app.VabEditField.Value = 120;
                V = [app.VabEditField.Value (app.VabEditField.Value + 30) (app.VabEditField.Value + 70)];
                Ang = [app.AabEditField.Value (app.AabEditField.Value + 90) (app.AabEditField.Value + 210)];
                data = app.setFasorPolar(V, Ang);
                app.grafica(app.UIAxes, data);
                %magnitudes
                app.VbcEditField.Value = app.VabEditField.Value +30;
                app.VcaEditField.Value = app.VabEditField.Value + 70;
                app.VabSlider.Value = app.VabEditField.Value;
                app.VbcSlider.Value = app.VabEditField.Value + 30;
                app.VcaSlider.Value = app.VabEditField.Value + 70;
                %angulos
                app.AbcEditField.Value = app.AabEditField.Value + 90;
                app.AcaEditField.Value = app.AabEditField.Value + 210;
                app.AabSlider.Value = app.AabEditField.Value;
                app.AbcSlider.Value = app.AabEditField.Value + 90;
                app.AcaSlider.Value = app.AabEditField.Value + 210;
                
                compSim = app.setCompSim(data{3}');
                Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
                Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
                data2 = app.setFasorPolar(Magnitud, Angulo);
                app.grafica(app.UIAxes2, data2);  
                app.setText2(Magnitud, Angulo);
                
                totalcS = app.totalCompSim(compSim);
                app.graficaComSim(app.UIAxes3 ,totalcS, data);                
                
                app.setText();
            end
            
        end

        % Selection changed function: AccionesRpidasButtonGroup
        function AccionesRpidasButtonGroupSelectionChanged(app, event)
            selectedButton = app.AccionesRpidasButtonGroup.SelectedObject;
            val = selectedButton.Text;
            
            if strcmp(val, 'Equilibrado')
                %para gráficar
                app.AabEditField.Value = 0;
                app.VabEditField.Value = 120;
                V = [app.VabEditField.Value app.VabEditField.Value app.VabEditField.Value];
                Ang = [app.AabEditField.Value (app.AabEditField.Value + 120) (app.AabEditField.Value + 240)];
                data = app.setFasorPolar(V, Ang);
                app.grafica(app.UIAxes, data);
                %magnitudes
                app.VbcEditField.Value = app.VabEditField.Value;
                app.VcaEditField.Value = app.VabEditField.Value;
                app.VabSlider.Value = app.VabEditField.Value;
                app.VbcSlider.Value = app.VabEditField.Value;
                app.VcaSlider.Value = app.VabEditField.Value;
                %angulos
                app.AbcEditField.Value = app.AabEditField.Value + 120;
                app.AcaEditField.Value = app.AabEditField.Value + 240;
                app.AabSlider.Value = app.AabEditField.Value;
                app.AbcSlider.Value = app.AabEditField.Value + 120;
                app.AcaSlider.Value = app.AabEditField.Value + 240;
                
                compSim = app.setCompSim(data{3}');
                Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
                Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
                data2 = app.setFasorPolar(Magnitud, Angulo);
                app.grafica(app.UIAxes2, data2);      
                app.setText2(Magnitud, Angulo);
                
                totalcS = app.totalCompSim(compSim);
                app.graficaComSim(app.UIAxes3 ,totalcS, data);                
                
                app.setText();
                
            elseif strcmp(val, 'Desequilibrado')
                %para gráficar
                app.AabEditField.Value = 0;
                app.VabEditField.Value = 120;
                V = [app.VabEditField.Value (app.VabEditField.Value + 30) (app.VabEditField.Value + 70)];
                Ang = [app.AabEditField.Value (app.AabEditField.Value + 90) (app.AabEditField.Value + 210)];
                data = app.setFasorPolar(V, Ang);
                app.grafica(app.UIAxes, data);
                %magnitudes
                app.VbcEditField.Value = app.VabEditField.Value +30;
                app.VcaEditField.Value = app.VabEditField.Value + 70;
                app.VabSlider.Value = app.VabEditField.Value;
                app.VbcSlider.Value = app.VabEditField.Value + 30;
                app.VcaSlider.Value = app.VabEditField.Value + 70;
                %angulos
                app.AbcEditField.Value = app.AabEditField.Value + 90;
                app.AcaEditField.Value = app.AabEditField.Value + 210;
                app.AabSlider.Value = app.AabEditField.Value;
                app.AbcSlider.Value = app.AabEditField.Value + 90;
                app.AcaSlider.Value = app.AabEditField.Value + 210;
                
                compSim = app.setCompSim(data{3}');
                Magnitud = [abs(compSim(1)) abs(compSim(2)) abs(compSim(3))];
                Angulo = [angle(compSim(1)) angle(compSim(2)) angle(compSim(3))]*180/pi;
                data2 = app.setFasorPolar(Magnitud, Angulo);
                app.grafica(app.UIAxes2, data2);     
                app.setText2(Magnitud, Angulo);
                
                totalcS = app.totalCompSim(compSim);
                app.graficaComSim(app.UIAxes3 ,totalcS, data);                
                
                app.setText();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraficaTab = uitab(app.TabGroup);
            app.GraficaTab.Title = 'Grafica';
            app.UIAxes3 = uiaxes(app.GraficaTab);
            title(app.UIAxes3, 'Gráfica Total')
            xlabel(app.UIAxes3, 'Real')
            ylabel(app.UIAxes3, 'Imaginario')
            app.VoltajesTab = uitab(app.TabGroup);
            app.VoltajesTab.Title = 'Voltajes';
            app.UIAxes = uiaxes(app.VoltajesTab);
            title(app.UIAxes, 'Gráfica Voltajes')
            xlabel(app.UIAxes, 'Real')
            ylabel(app.UIAxes, 'Imaginario')
            app.ComSimetricasTab = uitab(app.TabGroup);
            app.ComSimetricasTab.Title = 'Com. Simetricas';
            app.UIAxes2 = uiaxes(app.ComSimetricasTab);
            title(app.UIAxes2, 'Componentes Simétricas')
            xlabel(app.UIAxes2, 'Real')
            ylabel(app.UIAxes2, 'Imaginario')
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.MagnitudTab = uitab(app.TabGroup2);
            app.MagnitudTab.Title = 'Magnitud';
            app.MagnituddevoltajePanel = uipanel(app.MagnitudTab);
            app.MagnituddevoltajePanel.Title = 'Magnitud de voltaje';
            app.VabSliderLabel = uilabel(app.MagnituddevoltajePanel);
            app.VabSliderLabel.Text = 'Vab';
            app.VabSlider = uislider(app.MagnituddevoltajePanel);
            app.VabSlider.Limits = [0 400];
            app.VabSlider.ValueChangingFcn = createCallbackFcn(app, @VabSliderValueChanging, true);
            app.VbcSliderLabel = uilabel(app.MagnituddevoltajePanel);
            app.VbcSliderLabel.Text = 'Vbc';
            app.VbcSlider = uislider(app.MagnituddevoltajePanel);
            app.VbcSlider.Limits = [0 400];
            app.VbcSlider.ValueChangingFcn = createCallbackFcn(app, @VbcSliderValueChanging, true);
            app.VcaSliderLabel = uilabel(app.MagnituddevoltajePanel);
            app.VcaSliderLabel.Text = 'Vca';
            app.VcaSlider = uislider(app.MagnituddevoltajePanel);
            app.VcaSlider.Limits = [0 400];
            app.VcaSlider.ValueChangingFcn = createCallbackFcn(app, @VcaSliderValueChanging, true);
            app.VabEditFieldLabel = uilabel(app.MagnituddevoltajePanel);
            app.VabEditFieldLabel.Text = 'Vab';
            app.VabEditField = uieditfield(app.MagnituddevoltajePanel, 'numeric');
            app.VabEditField.ValueChangedFcn = createCallbackFcn(app, @VabEditFieldValueChanged, true);
            app.VbcEditFieldLabel = uilabel(app.MagnituddevoltajePanel);
            app.VbcEditFieldLabel.Text = 'Vbc';
            app.VbcEditField = uieditfield(app.MagnituddevoltajePanel, 'numeric');
            app.VbcEditField.ValueChangedFcn = createCallbackFcn(app, @VbcEditFieldValueChanged, true);
            app.VcaEditFieldLabel = uilabel(app.MagnituddevoltajePanel);
            app.VcaEditFieldLabel.Text = 'Vca';
            app.VcaEditField = uieditfield(app.MagnituddevoltajePanel, 'numeric');
            app.VcaEditField.ValueChangedFcn = createCallbackFcn(app, @VcaEditFieldValueChanged, true);
            app.AccionesRpidasButtonGroup = uibuttongroup(app.MagnitudTab);
            app.AccionesRpidasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AccionesRpidasButtonGroupSelectionChanged, true);
            app.AccionesRpidasButtonGroup.Title = 'Acciones Rápidas';
            app.EquilibradoButton = uiradiobutton(app.AccionesRpidasButtonGroup);
            app.EquilibradoButton.Text = 'Equilibrado';
            app.EquilibradoButton.Value = true;
            app.DesequilibradoButton = uiradiobutton(app.AccionesRpidasButtonGroup);
            app.DesequilibradoButton.Text = 'Desequilibrado';
            app.AnguloTab = uitab(app.TabGroup2);
            app.AnguloTab.Title = 'Angulo';
            app.AngulogradosPanel = uipanel(app.AnguloTab);
            app.AngulogradosPanel.Title = 'Angulo (grados)';
            app.AabSliderLabel = uilabel(app.AngulogradosPanel);
            app.AabSliderLabel.Text = 'Aab';
            app.AabSlider = uislider(app.AngulogradosPanel);
            app.AabSlider.Limits = [0 360];
            app.AabSlider.ValueChangingFcn = createCallbackFcn(app, @AabSliderValueChanging, true);
            app.AbcSliderLabel = uilabel(app.AngulogradosPanel);
            app.AbcSliderLabel.Text = 'Abc';
            app.AbcSlider = uislider(app.AngulogradosPanel);
            app.AbcSlider.Limits = [0 360];
            app.AbcSlider.ValueChangingFcn = createCallbackFcn(app, @AbcSliderValueChanging, true);
            app.AcaSliderLabel = uilabel(app.AngulogradosPanel);
            app.AcaSliderLabel.Text = 'Aca';
            app.AcaSlider = uislider(app.AngulogradosPanel);
            app.AcaSlider.Limits = [0 360];
            app.AcaSlider.ValueChangingFcn = createCallbackFcn(app, @AcaSliderValueChanging, true);
            app.AabEditFieldLabel = uilabel(app.AngulogradosPanel);
            app.AabEditFieldLabel.Text = 'Aab';
            app.AabEditField = uieditfield(app.AngulogradosPanel, 'numeric');
            app.AabEditField.ValueChangedFcn = createCallbackFcn(app, @AabEditFieldValueChanged, true);
            app.AbcEditFieldLabel = uilabel(app.AngulogradosPanel);
            app.AbcEditFieldLabel.Text = 'Abc';
            app.AbcEditField = uieditfield(app.AngulogradosPanel, 'numeric');
            app.AbcEditField.ValueChangedFcn = createCallbackFcn(app, @AbcEditFieldValueChanged, true);
            app.AcaEditFieldLabel = uilabel(app.AngulogradosPanel);
            app.AcaEditFieldLabel.Text = 'Aca';
            app.AcaEditField = uieditfield(app.AngulogradosPanel, 'numeric');
            app.AcaEditField.ValueChangedFcn = createCallbackFcn(app, @AcaEditFieldValueChanged, true);
            app.AccionesRpidasButtonGroup_2 = uibuttongroup(app.AnguloTab);
            app.AccionesRpidasButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @AccionesRpidasButtonGroup_2SelectionChanged, true);
            app.AccionesRpidasButtonGroup_2.Title = 'Acciones Rápidas';
            app.EquilibradoButton_2 = uiradiobutton(app.AccionesRpidasButtonGroup_2);
            app.EquilibradoButton_2.Text = 'Equilibrado';
            app.EquilibradoButton_2.Value = true;
            app.DesequilibradoButton_2 = uiradiobutton(app.AccionesRpidasButtonGroup_2);
            app.DesequilibradoButton_2.Text = 'Desequilibrado';
            app.COMPONENTESSIMTRICASLabel = uilabel(app.UIFigure);
            app.COMPONENTESSIMTRICASLabel.Text = 'COMPONENTES SIMÉTRICAS';
            app.VoltajesyComponentesSimtricasPanel = uipanel(app.UIFigure);
            app.VoltajesyComponentesSimtricasPanel.Title = 'Voltajes y Componentes Simétricas';
            app.Label = uilabel(app.VoltajesyComponentesSimtricasPanel);
            app.Label_2 = uilabel(app.VoltajesyComponentesSimtricasPanel);
            app.Label_3 = uilabel(app.VoltajesyComponentesSimtricasPanel);
            app.Label_4 = uilabel(app.VoltajesyComponentesSimtricasPanel);
            app.Label_5 = uilabel(app.VoltajesyComponentesSimtricasPanel);
            app.Label_6 = uilabel(app.VoltajesyComponentesSimtricasPanel);
            app.ProgramarealizadoporErikPaneluisael30deAbrildel2023Label = uilabel(app.UIFigure);
            app.ProgramarealizadoporErikPaneluisael30deAbrildel2023Label.Text = 'Programa realizado por Erik Paneluisa el 30 de Abril del 2023';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Componentes_Simetricas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end